classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        loadSourceDataShortcut          matlab.ui.container.toolbar.PushTool
        refreshShortcut                 matlab.ui.container.toolbar.PushTool
        zarovnajMapuShortcut            matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        TvorbazdrojovychdatTab          matlab.ui.container.Tab
        KoregistraciaPanel              matlab.ui.container.Panel
        KoregMriezka                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        ReferencnesnimkyTab             matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        ZobrazmodelKorButton            matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        AxialnyTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        pouzitkazduAx                   matlab.ui.control.Label
        StopSpinnerKorRefAx             matlab.ui.control.Spinner
        StepSpinnerKorRefAx             matlab.ui.control.Spinner
        snimkuLabel_3                   matlab.ui.control.Label
        StartSpinnerKorRefAx            matlab.ui.control.Spinner
        spociatocnousnimkouLabel_3      matlab.ui.control.Label
        akoncovousnimkouLabel_2         matlab.ui.control.Label
        KoronalnyTab_3                  matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        StopSpinnerKorRefCo             matlab.ui.control.Spinner
        StartSpinnerKorRefCo            matlab.ui.control.Spinner
        snimkuLabel_4                   matlab.ui.control.Label
        StepSpinnerKorRefCo             matlab.ui.control.Spinner
        akoncovousnimkouLabel_3         matlab.ui.control.Label
        spociatocnousnimkouLabel_4      matlab.ui.control.Label
        pouzitkazduLabel_4              matlab.ui.control.Label
        SagitalnyTab_3                  matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        StartSpinnerKorRefSa            matlab.ui.control.Spinner
        StopSpinnerKorRefSa             matlab.ui.control.Spinner
        akoncovousnimkouLabel_4         matlab.ui.control.Label
        spociatocnousnimkouLabel_5      matlab.ui.control.Label
        snimkuLabel_5                   matlab.ui.control.Label
        StepSpinnerKorRefSa             matlab.ui.control.Spinner
        pouzitkazduLabel_5              matlab.ui.control.Label
        DostupnesadysnimokreferencneLabel  matlab.ui.control.Label
        DostupnesadyRefListBox          matlab.ui.control.ListBox
        MapyTab                         matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ZobrazmapyButton                matlab.ui.control.Button
        pouzitkazduLabel_2              matlab.ui.control.Label
        StepSpinnerKorMapa              matlab.ui.control.Spinner
        snimkuLabel_2                   matlab.ui.control.Label
        spociatocnousnimkouLabel_2      matlab.ui.control.Label
        StartSpinnerKorMapa             matlab.ui.control.Spinner
        DostupnesadyMapyListBox         matlab.ui.control.ListBox
        DostupnesadysnimokmapyLabel     matlab.ui.control.Label
        KorekcieTab_2                   matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        TabGroup2_2                     matlab.ui.container.TabGroup
        KoronalnyTabK                   matlab.ui.container.Tab
        GridLayout10_2                  matlab.ui.container.GridLayout
        KZflipK                         matlab.ui.control.Button
        KYplusK                         matlab.ui.control.Button
        KZminusK                        matlab.ui.control.Button
        KYFlipK                         matlab.ui.control.Button
        KZplusK                         matlab.ui.control.Button
        KYminusK                        matlab.ui.control.Button
        KXflipK                         matlab.ui.control.Button
        KXplusK                         matlab.ui.control.Button
        KXminusK                        matlab.ui.control.Button
        ZLabelK                         matlab.ui.control.Label
        YLabelK                         matlab.ui.control.Label
        XLabelK                         matlab.ui.control.Label
        SagitalnyTabK                   matlab.ui.container.Tab
        GridLayout11_2                  matlab.ui.container.GridLayout
        SZflipK                         matlab.ui.control.Button
        SZplusK                         matlab.ui.control.Button
        SZminusK                        matlab.ui.control.Button
        SXflipK                         matlab.ui.control.Button
        SXplusK                         matlab.ui.control.Button
        SYminusK                        matlab.ui.control.Button
        SYflipK                         matlab.ui.control.Button
        SYplusK                         matlab.ui.control.Button
        SXminusK                        matlab.ui.control.Button
        ZLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        InterpolovanymodelTab           matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        ZarovnajKorButton               matlab.ui.control.Button
        VytvormodelInterpButtonKor      matlab.ui.control.Button
        MenomodeluEditField_2Label_2    matlab.ui.control.Label
        MenoMaskyKorEditField           matlab.ui.control.EditField
        MenomodeluEditField_2Label      matlab.ui.control.Label
        MenomodeluKorEditField          matlab.ui.control.EditField
        ZdrojedatDropDownLabel          matlab.ui.control.Label
        ZdrojedatDropDown               matlab.ui.control.DropDown
        ZobrazenieViacPanel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        StopSpinner                     matlab.ui.control.Spinner
        akoncovousnimkouLabel           matlab.ui.control.Label
        StartSpinner                    matlab.ui.control.Spinner
        spociatocnousnimkouLabel        matlab.ui.control.Label
        snimkuLabel                     matlab.ui.control.Label
        StepSpinner                     matlab.ui.control.Spinner
        pouzitkazduLabel                matlab.ui.control.Label
        KorekciemodelovPanel            matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        AplikovatkorekcieLabel          matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        KoronalnyTab                    matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        KZflip                          matlab.ui.control.Button
        KYplus                          matlab.ui.control.Button
        KZminus                         matlab.ui.control.Button
        KYFlip                          matlab.ui.control.Button
        KZplus                          matlab.ui.control.Button
        KYminus                         matlab.ui.control.Button
        KXflip                          matlab.ui.control.Button
        KXplus                          matlab.ui.control.Button
        KXminus                         matlab.ui.control.Button
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        SagitalnyTab                    matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SZflip                          matlab.ui.control.Button
        SZplus                          matlab.ui.control.Button
        SZminus                         matlab.ui.control.Button
        SXflip                          matlab.ui.control.Button
        SXplus                          matlab.ui.control.Button
        SYminus                         matlab.ui.control.Button
        SYflip                          matlab.ui.control.Button
        SYplus                          matlab.ui.control.Button
        SXminus                         matlab.ui.control.Button
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        ZAPVYPButton_2                  matlab.ui.control.StateButton
        MenomodeluEditField             matlab.ui.control.EditField
        MenomodeluEditFieldLabel        matlab.ui.control.Label
        DostupnesadysnimokListBox_2Label  matlab.ui.control.Label
        DostupnesadyVListBox            matlab.ui.control.ListBox
        VytvormodelVButton              matlab.ui.control.Button
        VytvormodelInterpButton         matlab.ui.control.Button
        ZobrazenieJednaPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DostupnesadysnimokListBoxLabel  matlab.ui.control.Label
        DostupnesadyJListBox            matlab.ui.control.ListBox
        VytvormodelJButton              matlab.ui.control.Button
        NastaveniazobrazeniaTab         matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MaskThresholdMapySpinner        matlab.ui.control.Spinner
        MaskThresholdMapySlider         matlab.ui.control.Slider
        MaskovacithresholdmapySliderLabel  matlab.ui.control.Label
        FarebnostprelozenejmapyListBox  matlab.ui.control.ListBox
        FarebnostprelozenejmapyListBoxLabel  matlab.ui.control.Label
        FarebnostmodeluListBox          matlab.ui.control.ListBox
        FarebnostmodeluListBoxLabel     matlab.ui.control.Label
        VoxelovymultiplierLabel         matlab.ui.control.Label
        MaskMultiplier                  matlab.ui.control.Slider
        MaskovacithresholdmodeluLabel   matlab.ui.control.Label
        MaskThresholdModeluSlider       matlab.ui.control.Slider
        InterpolaciaSpinnerLabel        matlab.ui.control.Label
        InterpolaciaSpinner             matlab.ui.control.Spinner
        MaskThresholdModeluSpinner      matlab.ui.control.Spinner
        RezimprojekcieButtonGroup       matlab.ui.container.ButtonGroup
        PerspectiveButton               matlab.ui.control.RadioButton
        OrthographicButton_2            matlab.ui.control.RadioButton
        LayeringTab                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        PouzivathodnotyvdropdownochButton  matlab.ui.control.StateButton
        MapaDropDown                    matlab.ui.control.DropDown
        MapanaprelozenieLabel           matlab.ui.control.Label
        HlavnymodelDropDownLabel        matlab.ui.control.Label
        HlavnymodelDropDown             matlab.ui.control.DropDown
        CenterPanel                     matlab.ui.container.Panel
        StredGroup                      matlab.ui.container.TabGroup
        ZobModTab                       matlab.ui.container.Tab
        ViewerPanel                     matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Viewer                          matlab.ui.control.UIAxes
        ZobZarTab                       matlab.ui.container.Tab
        ZobrPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ZobrSaMapa                      matlab.ui.control.UIAxes
        ZobrCoMapa                      matlab.ui.control.UIAxes
        ZobrAxMapa                      matlab.ui.control.UIAxes
        ZobrSa                          matlab.ui.control.UIAxes
        ZobrCo                          matlab.ui.control.UIAxes
        ZobrAx                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        StopkaButton                    matlab.ui.control.Button
        XYrotSliderLabel                matlab.ui.control.Label
        XYrotSlider                     matlab.ui.control.Slider
        ZrotSliderLabel                 matlab.ui.control.Label
        ZrotSlider                      matlab.ui.control.Slider
        ZAPVYPButton                    matlab.ui.control.StateButton
        PrehliadaniepovrstvachLabel     matlab.ui.control.Label
        VrstvyPanel                     matlab.ui.container.Panel
        VrstvyLayout                    matlab.ui.container.GridLayout
        ZHSlider                        matlab.ui.control.Slider
        YHSlider                        matlab.ui.control.Slider
        XLSlider                        matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        ZLSlider                        matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        YLSlider                        matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        XHSlider                        matlab.ui.control.Slider
        Stav                            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sourceData % vstupne data
        volumeData % Description
        nahlady % Description
        stopkaStatus % Description
    end
    
    properties (Access = public)
        modelSingle % Description
        modelMultiple % Description
        zobrazenie % vsetko co sa tyka zobrazovacieho okna
        zobrazenieVlastnosti % Description
        zobrazenieViewer % Description
        zobrazenieModel % Description
        zobrazenieMapa % Description
        modelMultipleMaps % Description
    end
    
    methods (Access = private)
        
        function singleOrMultipleSwitch(app)
            app.sourceData.stepZmeneny=false;
            app.modelMultipleMaps.inUse=false;
            if contains(lower(app.ZdrojedatDropDown.Value),'jeden')
                app.ZobrPanel.Enable='off';
                app.ZobrazenieJednaPanel.Visible='on';
                app.ZobrazenieJednaPanel.Enable='on';
                app.ZobrazenieViacPanel.Visible='off';
                app.ZobrazenieViacPanel.Enable='off';
                app.KoregistraciaPanel.Visible='off';
                app.KoregistraciaPanel.Enable='off';
            elseif contains(lower(app.ZdrojedatDropDown.Value),'viacero')
                app.ZobrPanel.Enable='on';
                app.ZobrazenieJednaPanel.Visible='off';
                app.ZobrazenieJednaPanel.Enable='off';
                app.ZobrazenieViacPanel.Visible='on';
                app.ZobrazenieViacPanel.Enable='on';
                app.KoregistraciaPanel.Visible='off';
                app.KoregistraciaPanel.Enable='off';
            else
                app.ZobrPanel.Enable='on';
                app.ZobrazenieJednaPanel.Visible='off';
                app.ZobrazenieJednaPanel.Enable='off';
                app.ZobrazenieViacPanel.Visible='off';
                app.ZobrazenieViacPanel.Enable='off';
                app.KoregistraciaPanel.Visible='on';
                app.KoregistraciaPanel.Enable='on';
            end
        end
        
        function drawRelevant(app)
            tab=app.StredGroup.SelectedTab.Title;
            app.Stav.Text="Prekreslujem zobrazenie...";
            if app.zobrazenieVlastnosti.pouzitRoletky==true
                if ~isempty(app.zobrazenieModel)&&~contains(app.HlavnymodelDropDown.Value,"BEZ MODELU",'IgnoreCase',true)
                    hlavny=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                elseif contains(app.HlavnymodelDropDown.Value,"BEZ MODELU",'IgnoreCase',true)
                    %nic sa nedeje :D
                else
                    uhOh(app,"Nebolo mozne zobrazit model!");
                end
                hold(app.Viewer,'on');
                if contains(lower(app.zobrazenieVlastnosti.pohlad),'ortho')
                    camproj(app.Viewer,'orthographic');
                elseif contains(lower(app.zobrazenieVlastnosti.pohlad),'persp')
                    camproj(app.Viewer,'perspective');
                end
                if ~isempty(app.zobrazenieMapa)&&~contains(app.MapaDropDown.Value,"BEZ MAPY",'IgnoreCase',true)
                    overlay=modelVisualizer(app.zobrazenieMapa,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.mapaThreshold,app.zobrazenieVlastnosti.mapaMaska);
                elseif contains(app.MapaDropDown.Value,"BEZ MAPY",'IgnoreCase',true)
                    %nic :D
                else
                    uhOh(app,"Nebolo mozne zobrazit mapu!");
                end
                set(app.Viewer,'Color','k');
                title(app.Viewer,'');
                axis(app.Viewer,'equal');
                xlabel(app.Viewer,'X'); ylabel(app.Viewer,'Y'); zlabel(app.Viewer,'Z');
                view(app.Viewer,3);
                hold(app.Viewer,'off');
            else
                if contains(tab,"3D",'IgnoreCase',true)
                    if ~isempty(app.zobrazenieModel)
                        app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                        if contains(lower(app.zobrazenieVlastnosti.pohlad),'ortho')
                            camproj(app.Viewer,'orthographic');
                        elseif contains(lower(app.zobrazenieVlastnosti.pohlad),'persp')
                            camproj(app.Viewer,'perspective');
                        end
                    else
                        uhOh(app,"Nebolo mozne zobrazit model!");
                    end
                elseif contains(tab,"zarovnanie",'IgnoreCase',true)
                    if contains(app.ZdrojedatDropDown.Value,"koregistracia",'IgnoreCase',true)
                        hold(app.Viewer,'on');
                        %axial
                        if ~isempty(app.modelMultiple.axial)
                            modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrAx,'off')
                            title(app.ZobrAx,'Axialne zobrazenie');
                            modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                        else
                            uhOh(app,"Nebolo mozne zobrazit axialny model!");
                        end
                        %coronal
                        if ~isempty(app.modelMultiple.coronal)
                            modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrCo,'off')
                            title(app.ZobrCo,'Koronalne zobrazenie');
                            modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                        else
                            uhOh(app,"Nebolo mozne zobrazit koronalny model!");
                        end
                        %sagittal
                        if ~isempty(app.modelMultiple.sagittal)
                            modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrSa,'off')
                            title(app.ZobrSa,'Sagitalne zobrazenie');
                            modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                        else
                            uhOh(app,"Nebolo mozne zobrazit sagitalny model!");
                        end
                        hold(app.Viewer,'off');
                        if app.modelMultipleMaps.inUse==true
                            %axial mapa
                            if ~isempty(app.modelMultipleMaps.axial)
                                modelVisualizer(app.modelMultipleMaps.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAxMapa,app.zobrazenieVlastnosti.mapaThreshold,app.zobrazenieVlastnosti.mapaMaska);
                                colorbar(app.ZobrAxMapa,'off')
                                title(app.ZobrAxMapa,'Axialne zobrazenie - mapa');
                            else
                                uhOh(app,"Nebolo mozne zobrazit axialnu mapu!");
                            end
                            %coronal mapa
                            if ~isempty(app.modelMultipleMaps.coronal)
                                modelVisualizer(app.modelMultipleMaps.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCoMapa,app.zobrazenieVlastnosti.mapaThreshold,app.zobrazenieVlastnosti.mapaMaska);
                                colorbar(app.ZobrCoMapa,'off')
                                title(app.ZobrCoMapa,'Koronalne zobrazenie - mapa');
                            else
                                uhOh(app,"Nebolo mozne zobrazit koronalnu mapu!");
                            end
                            %sagittal mapa
                            if ~isempty(app.modelMultipleMaps.sagittal)
                                modelVisualizer(app.modelMultipleMaps.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSaMapa,app.zobrazenieVlastnosti.mapaThreshold,app.zobrazenieVlastnosti.mapaMaska);
                                colorbar(app.ZobrSaMapa,'off')
                                title(app.ZobrSaMapa,'Sagitalne zobrazenie - mapa');
                            else
                                uhOh(app,"Nebolo mozne zobrazit sagitalnu mapu!");
                            end
                        end
                        if contains(app.zobrazenieVlastnosti.pohlad,"ortho",'IgnoreCase',true)
                            camproj(app.ZobrAx,'orthographic');
                            camproj(app.ZobrCo,'orthographic');
                            camproj(app.ZobrSa,'orthographic');
                            camproj(app.ZobrAxMapa,'orthographic');
                            camproj(app.ZobrSaMapa,'orthographic');
                            camproj(app.ZobrCoMapa,'orthographic');
                        elseif contains(app.zobrazenieVlastnosti.pohlad,"persp",'IgnoreCase',true)
                            camproj(app.ZobrAx,'perspective');
                            camproj(app.ZobrCo,'perspective');
                            camproj(app.ZobrSa,'perspective');
                            camproj(app.ZobrAxMapa,'perspective');
                            camproj(app.ZobrSaMapa,'perspective');
                            camproj(app.ZobrCoMapa,'perspective');
                        end
                        set(app.ZobrAx,'Color','k'); set(app.ZobrCo,'Color','k'); set(app.ZobrSa,'Color','k');
                        set(app.ZobrAxMapa,'Color','k'); set(app.ZobrSaMapa,'Color','k'); set(app.ZobrCoMapa,'Color','k');
                        axis(app.ZobrAx,'equal'); axis(app.ZobrCo,'equal'); axis(app.ZobrSa,'equal');
                        axis(app.ZobrAxMapa,'equal'); axis(app.ZobrSaMapa,'equal'); axis(app.ZobrCoMapa,'equal');
                        xlabel(app.ZobrAx,'X'); ylabel(app.ZobrAx,'Y'); zlabel(app.ZobrAx,'Z');
                        xlabel(app.ZobrCo,'X'); ylabel(app.ZobrCo,'Y'); zlabel(app.ZobrCo,'Z');
                        xlabel(app.ZobrSa,'X'); ylabel(app.ZobrSa,'Y'); zlabel(app.ZobrSa,'Z');
                        xlabel(app.ZobrAxMapa,'X'); ylabel(app.ZobrAxMapa,'Y'); zlabel(app.ZobrAxMapa,'Z');
                        xlabel(app.ZobrSaMapa,'X'); ylabel(app.ZobrSaMapa,'Y'); zlabel(app.ZobrSaMapa,'Z');
                        xlabel(app.ZobrCoMapa,'X'); ylabel(app.ZobrCoMapa,'Y'); zlabel(app.ZobrCoMapa,'Z');
                    else
                        %axial
                        if ~isempty(app.modelMultiple.axial)
                            modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrAx,'off')
                            title(app.ZobrAx,'Axialne zobrazenie');
                        else
                            uhOh(app,"Nebolo mozne zobrazit axialny model!");
                        end
                        %coronal
                        if ~isempty(app.modelMultiple.coronal)
                            modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrCo,'off')
                            title(app.ZobrCo,'Koronalne zobrazenie');
                        else
                            uhOh(app,"Nebolo mozne zobrazit koronalny model!");
                        end
                        %sagittal
                        if ~isempty(app.modelMultiple.sagittal)
                            modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                            colorbar(app.ZobrSa,'off')
                            title(app.ZobrSa,'Sagitalne zobrazenie');
                        else
                            uhOh(app,"Nebolo mozne zobrazit sagitalny model!");
                        end
                        if contains(app.zobrazenieVlastnosti.pohlad,"ortho",'IgnoreCase',true)
                            camproj(app.ZobrAx,'orthographic');
                            camproj(app.ZobrCo,'orthographic');
                            camproj(app.ZobrSa,'orthographic');
                        elseif contains(app.zobrazenieVlastnosti.pohlad,"persp",'IgnoreCase',true)
                            camproj(app.ZobrAx,'perspective');
                            camproj(app.ZobrCo,'perspective');
                            camproj(app.ZobrSa,'perspective');
                        end
                        set(app.ZobrAx,'Color','k'); set(app.ZobrCo,'Color','k'); set(app.ZobrSa,'Color','k');
                        axis(app.ZobrAx,'equal'); axis(app.ZobrCo,'equal'); axis(app.ZobrSa,'equal');
                        xlabel(app.ZobrAx,'X'); ylabel(app.ZobrAx,'Y'); zlabel(app.ZobrAx,'Z');
                        xlabel(app.ZobrCo,'X'); ylabel(app.ZobrCo,'Y'); zlabel(app.ZobrCo,'Z');
                        xlabel(app.ZobrSa,'X'); ylabel(app.ZobrSa,'Y'); zlabel(app.ZobrSa,'Z');
                        view(app.ZobrAx,3); view(app.ZobrCo,3); view(app.ZobrSa,3);
                    end
                end
            end
            app.Stav.Text="Zobrazenie prekreslene!";
        end
        
        function zarovnajMapu(app)
            if app.zobrazenieVlastnosti.pouzitRoletky==true
                mapa = imresize3(niftiread("niiData\"+app.MapaDropDown.Value),[256 256 256]);
                app.Stav.Text="Zarovnavam mapu k modelu...";
                address=string(["niiData\"+app.MapaDropDown.Value]);
                [optimizer,metric]=imregconfig('monomodal');
                optimizer.MaximumStepLength=0.00625;
                map_reg=imregister(mapa,app.zobrazenieModel,'affine',optimizer,metric,'DisplayOptimization',true);
                niftiwrite(map_reg,address);
                app.zobrazenieMapa=map_reg;
                app.Stav.Text="Mapa zarovnana!";
            else
                uhOh(app,"Nemozno zarovnat mapu! Pouzivanie hodnot v dropdownoch je vypnute, takze skript nevie, ktoru mapu pouzit.");
            end
        end
        
        function uhOh(app,hlaska) %tato funkcia sa vola vtedy, ked aplikacia narazi napriklad na nejaky chybny vstup. pauzne vykonavanie kym pouzivatel neodsuhlasi pokracovanie
            app.StopkaButton.Icon = 'icon-mat-thumb-up.png';
            app.stopkaStatus=true;
            app.Stav.Text=string(hlaska);
            app.StopkaButton.Enable=true;
            uiwait(app.UIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.sourceData.content,app.sourceData.Path,inputName,app.volumeData.content,app.volumeData.Path,volumeName]=loadSourceData();
            app.DostupnesadyJListBox.Items = inputName(:);
            app.DostupnesadyVListBox.Items = inputName(:);
            app.DostupnesadyRefListBox.Items = inputName(:);
            app.DostupnesadyMapyListBox.Items = inputName(:);
            app.HlavnymodelDropDown.Items = ["BEZ MODELU";volumeName(:)];
            app.MapaDropDown.Items = ["BEZ MAPY";volumeName(:)];
            app.zobrazenieVlastnosti.sample=app.InterpolaciaSpinner.Value;
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdModeluSlider.Value;
            app.zobrazenieVlastnosti.mapaThreshold = app.MaskThresholdMapySlider.Value;
            app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
            app.zobrazenieVlastnosti.pohlad='orthographic';
            app.zobrazenieVlastnosti.pouzitRoletky=false;
            app.zobrazenieVlastnosti.mapaHlavny="gray";
            app.zobrazenieVlastnosti.mapaMaska="parula";
            app.modelMultiple.axial=double.empty;
            app.modelMultiple.coronal=double.empty;
            app.modelMultiple.sagittal=double.empty;
            app.modelMultipleMaps.axial=double.empty;
            app.modelMultipleMaps.coronal=double.empty;
            app.modelMultipleMaps.sagittal=double.empty;
            app.sourceData.stepZmeneny=false;
            app.PouzivathodnotyvdropdownochButton.Value=app.zobrazenieVlastnosti.pouzitRoletky;
            app.nahlady=app.ZobrAx;
            app.InterpolaciaSpinner.Value=app.zobrazenieVlastnosti.sample;
            app.modelMultipleMaps.inUse=false;
            singleOrMultipleSwitch(app);
            app.Stav.Text="Cakam na vstup...";
        end

        % Button pushed function: VytvormodelJButton
        function VytvormodelJButtonPushed(app, event)
            app.Stav.Text="Nacitavam model...";
            if isempty(app.DostupnesadyJListBox.Value)
                uhOh(app,"Nezvolili ste model na zobrazenie!");
            else
                app.modelSingle.main=makeModelSingle(app.sourceData.Path,app.DostupnesadyJListBox.Value,app.DostupnesadyJListBox.Value);
                app.Stav.Text="Zobrazujem model...";
                app.zobrazenieModel=app.modelSingle.main;
                app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                app.Stav.Text="Model zobrazeny!";
            end
        end

        % Value changed function: InterpolaciaSpinner
        function InterpolaciaSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.sample = app.InterpolaciaSpinner.Value;
            %             drawRelevant(app);
        end

        % Value changed function: ZdrojedatDropDown
        function ZdrojedatDropDownValueChanged(app, event)
            singleOrMultipleSwitch(app);
        end

        % Value changed function: MaskThresholdModeluSlider
        function MaskThresholdModeluSliderValueChanged(app, event)
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdModeluSlider.Value;
            app.MaskThresholdModeluSpinner.Value = app.zobrazenieVlastnosti.threshold;
            %             drawRelevant(app);
        end

        % Value changed function: MaskMultiplier
        function MaskMultiplierValueChanged(app, event)
            app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
            %             drawRelevant(app);
        end

        % Button pushed function: VytvormodelVButton
        function VytvormodelVButtonPushed(app, event)
            if length(app.DostupnesadyVListBox.Value)~=3
                uhOh(app,"Pre uspesne zostavenie rezu potrebujete mat vybrane tri rezy, ktorych nazvy obsahuju axial, coronal a sagittal.");
            else
                app.Stav.Text="Nacitavam modely...";
                if app.sourceData.stepZmeneny==true
                    useSkips.tf=true;
                    useSkips.start=app.StartSpinner.Value;
                    useSkips.stop=app.StopSpinner.Value;
                    useSkips.step=app.StepSpinner.Value;
                else
                    useSkips.tf=false;
                end
                useSkips.isCoreg=false;
                [app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittal,app.modelMultiple.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyVListBox.Value,app.DostupnesadyVListBox.Value,useSkips);
                %                 figure(app.ZobrAx)
                app.Stav.Text="Zobrazujem modely...";
                %axial
                modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                colorbar(app.ZobrAx,'off')
                title(app.ZobrAx,'Axialne zobrazenie');
                %coronal
                modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                %sagittal
                colorbar(app.ZobrCo,'off')
                title(app.ZobrCo,'Koronalne zobrazenie');
                modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                colorbar(app.ZobrSa,'off')
                title(app.ZobrSa,'Sagitalne zobrazenie');
                set(app.ZobrAx,'Color','k'); set(app.ZobrCo,'Color','k'); set(app.ZobrSa,'Color','k');
                axis(app.ZobrAx,'equal'); axis(app.ZobrCo,'equal'); axis(app.ZobrSa,'equal');
                xlabel(app.ZobrAx,'X'); ylabel(app.ZobrAx,'Y'); zlabel(app.ZobrAx,'Z');
                xlabel(app.ZobrCo,'X'); ylabel(app.ZobrCo,'Y'); zlabel(app.ZobrCo,'Z');
                xlabel(app.ZobrSa,'X'); ylabel(app.ZobrSa,'Y'); zlabel(app.ZobrSa,'Z');
                view(app.ZobrAx,3); view(app.ZobrCo,3); view(app.ZobrSa,3);
                app.Stav.Text="Modely zobrazene!";
            end
        end

        % Button pushed function: VytvormodelInterpButton
        function VytvormodelInterpButtonPushed(app, event)
            app.Stav.Text="Vytvaram interpolovany model...";
            if isempty(app.MenomodeluEditField.Value)==false
                app.modelMultiple.interpAddress=string(["niiData\"+app.MenomodeluEditField.Value+".nii"]);
                app.modelMultiple.interpLabel=app.MenomodeluEditField.Value;
            else
                app.modelMultiple.interpAddress="niiData\GenerickeMenoInterpolovanehoModelu.nii";
                app.modelMultiple.interpLabel='GenerickeMenoInterpolovanehoModelu';
            end
            if ~isempty(app.modelMultiple)
                if ~contains(app.HlavnymodelDropDown.Items,app.modelMultiple.interpLabel)
                    app.HlavnymodelDropDown.Items(end+1)=app.HlavnymodelDropDown.Items(end);
                    app.HlavnymodelDropDown.Items(end)={app.modelMultiple.interpLabel};
                end
                app.HlavnymodelDropDown.Value=app.modelMultiple.interpLabel;
                app.modelMultiple.main=spatialMatrixInterp(app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.sagittal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittalAddress,app.modelMultiple.interpAddress,true);
                app.Stav.Text="Zobrazujem model...";
                app.zobrazenieModel=app.modelMultiple.main;
                app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                app.Stav.Text="Model zobrazeny!";
            else
                uhOh(app,"Nevhodne data na interpolaciu! Niektora z matic je prazdna.");
            end
        end

        % Value changed function: MaskThresholdModeluSpinner
        function MaskThresholdModeluSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdModeluSpinner.Value;
            app.MaskThresholdModeluSlider.Value = app.zobrazenieVlastnosti.threshold;
        end

        % Selection changed function: RezimprojekcieButtonGroup
        function RezimprojekcieButtonGroupSelectionChanged(app, event)
            app.zobrazenieVlastnosti.pohlad = app.RezimprojekcieButtonGroup.SelectedObject.Text;
        end

        % Value changing function: XYrotSlider
        function XYrotSliderValueChanging(app, event)
            changingValue = event.Value;
            camorbit(app.Viewer,changingValue,0,'data',[0 0 1]);
        end

        % Callback function: loadSourceDataShortcut
        function loadSourceDataShortcutClicked(app, event)
            [app.sourceData.content,app.sourceData.Path,inputName,app.volumeData.content,app.volumeData.Path,volumeName]=loadSourceData();
            app.DostupnesadyJListBox.Items = inputName(:);
            app.DostupnesadyVListBox.Items = inputName(:);
            app.DostupnesadyRefListBox.Items = inputName(:);
            app.DostupnesadyMapyListBox.Items = inputName(:);
            app.HlavnymodelDropDown.Items = ["BEZ MODELU";volumeName(:)];
            app.MapaDropDown.Items = ["BEZ MAPY";volumeName(:)];
        end

        % Callback function: refreshShortcut
        function refreshShortcutClicked(app, event)
            drawRelevant(app);
        end

        % Value changed function: HlavnymodelDropDown
        function HlavnymodelDropDownValueChanged(app, event)
            if ~contains(app.HlavnymodelDropDown.Value,"BEZ MODELU",'IgnoreCase',true)
                model=niftiread("niiData\"+app.HlavnymodelDropDown.Value);
                if isempty(model)
                    uhOh(app,"Subor ktory ste zvolili neobsahuje validne data!");
                else
                    app.zobrazenieModel = model;
                end
            end
        end

        % Value changed function: MapaDropDown
        function MapaDropDownValueChanged(app, event)
            if ~contains(app.MapaDropDown.Value,"BEZ MAPY",'IgnoreCase',true)
                mapa = niftiread("niiData\"+app.MapaDropDown.Value);
                if isempty(mapa)
                    uhOh(app,"Subor ktory ste zvolili neobsahuje validne data!");
                else
                    app.zobrazenieMapa = mapa;
                end
            end
        end

        % Button pushed function: KXminus
        function KXminusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[-1 0 0]);
        end

        % Button pushed function: KYminus
        function KYminusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 -1 0]);
        end

        % Button pushed function: KZminus
        function KZminusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 0 -1]);
        end

        % Button pushed function: KXplus
        function KXplusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[1 0 0]);
        end

        % Button pushed function: KYplus
        function KYplusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 1 0]);
        end

        % Button pushed function: KZplus
        function KZplusButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 0 1]);
        end

        % Button pushed function: KXflip
        function KXflipButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,1);
        end

        % Button pushed function: KYFlip
        function KYFlipButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,2);
        end

        % Button pushed function: KZflip
        function KZflipButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,3);
        end

        % Value changed function: PouzivathodnotyvdropdownochButton
        function PouzivathodnotyvdropdownochButtonValueChanged(app, event)
            app.zobrazenieVlastnosti.pouzitRoletky = app.PouzivathodnotyvdropdownochButton.Value;
            if app.zobrazenieVlastnosti.pouzitRoletky==true
                if ~contains(app.HlavnymodelDropDown.Value,"BEZ MODELU",'IgnoreCase',true)&&isempty(app.zobrazenieModel)
                    uhOh(app,"Model ktory sa snazite pouzit je prazdny. Skuste iny!");
                    app.PouzivathodnotyvdropdownochButton.Value=false;
                elseif ~contains(app.MapaDropDown.Value,"BEZ MAPY",'IgnoreCase',true)&&isempty(app.zobrazenieMapa)
                    uhOh(app,"Mapa ktoru sa snazite pouzit je prazdna. Skuste inu!");
                    app.PouzivathodnotyvdropdownochButton.Value=false;
                elseif contains(app.HlavnymodelDropDown.Value,"BEZ MODELU",'IgnoreCase',true)&&contains(app.MapaDropDown.Value,"BEZ MAPY",'IgnoreCase',true)
                    uhOh(app,"Nemate navoleny ani model ani mapu!");
                    app.PouzivathodnotyvdropdownochButton.Value=false;
                else
                    drawRelevant(app);
                end
            end
        end

        % Value changed function: FarebnostmodeluListBox
        function FarebnostmodeluListBoxValueChanged(app, event)
            app.zobrazenieVlastnosti.mapaHlavny = string(app.FarebnostmodeluListBox.Value);
        end

        % Value changed function: FarebnostprelozenejmapyListBox
        function FarebnostprelozenejmapyListBoxValueChanged(app, event)
            app.zobrazenieVlastnosti.mapaMaska = string(app.FarebnostprelozenejmapyListBox.Value);
        end

        % Callback function: zarovnajMapuShortcut
        function zarovnajMapuShortcutClicked(app, event)
            zarovnajMapu(app);
        end

        % Value changed function: StepSpinner
        function StepSpinnerValueChanged(app, event)
            app.sourceData.stepZmeneny=true;
        end

        % Button pushed function: ZobrazmodelKorButton
        function ZobrazmodelKorButtonPushed(app, event)
            app.Stav.Text="Nacitavam modely...";
            useSkips.tf=true;
            useSkips.isCoreg=true;
            useSkips.start.Ax=app.StartSpinnerKorRefAx.Value;
            useSkips.stop.Ax=app.StopSpinnerKorRefAx.Value;
            useSkips.step.Ax=app.StepSpinnerKorRefAx.Value;
            useSkips.start.Co=app.StartSpinnerKorRefCo.Value;
            useSkips.stop.Co=app.StopSpinnerKorRefCo.Value;
            useSkips.step.Co=app.StepSpinnerKorRefCo.Value;
            useSkips.start.Sa=app.StartSpinnerKorRefSa.Value;
            useSkips.stop.Sa=app.StopSpinnerKorRefSa.Value;
            useSkips.step.Sa=app.StepSpinnerKorRefSa.Value;
            if isempty(app.DostupnesadyRefListBox.Value)
                uhOh(app,"Nezvolili ste modely na zobrazenie!");
            elseif length(app.DostupnesadyRefListBox.Value)~=3
                uhOh(app,"Pre uspesne zarovnanie potrebujete mat vybrane tri modely, ktorych nazvy obsahuju axial, coronal a sagittal.");
            else
                [app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittal,app.modelMultiple.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyRefListBox.Value,app.DostupnesadyRefListBox.Value,useSkips);
                %                 figure(app.ZobrAx)
                app.Stav.Text="Zobrazujem modely...";
                if isempty(app.modelMultiple.axial)||isempty(app.modelMultiple.coronal)||isempty(app.modelMultiple.sagittal)
                    uhOh(app,"Nemate spravne nacitane snimky. Niektora z nich neobsahuje validne data.");
                else
                    %axial
                    modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                    colorbar(app.ZobrAx,'off')
                    title(app.ZobrAx,'Axialne zobrazenie');
                    %coronal
                    modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                    %sagittal
                    colorbar(app.ZobrCo,'off')
                    title(app.ZobrCo,'Koronalne zobrazenie');
                    modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                    colorbar(app.ZobrSa,'off')
                    title(app.ZobrSa,'Sagitalne zobrazenie');
                    set(app.ZobrAx,'Color','k'); set(app.ZobrCo,'Color','k'); set(app.ZobrSa,'Color','k');
                    axis(app.ZobrAx,'equal'); axis(app.ZobrCo,'equal'); axis(app.ZobrSa,'equal');
                    xlabel(app.ZobrAx,'X'); ylabel(app.ZobrAx,'Y'); zlabel(app.ZobrAx,'Z');
                    xlabel(app.ZobrCo,'X'); ylabel(app.ZobrCo,'Y'); zlabel(app.ZobrCo,'Z');
                    xlabel(app.ZobrSa,'X'); ylabel(app.ZobrSa,'Y'); zlabel(app.ZobrSa,'Z');
                    view(app.ZobrAx,3); view(app.ZobrCo,3); view(app.ZobrSa,3);
                    app.Stav.Text="Modely zobrazene!";
                end
            end
        end

        % Value changed function: StepSpinnerKorMapa
        function StepSpinnerKorMapaValueChanged(app, event)
            app.sourceData.stepZmeneny=true;
        end

        % Value changed function: StartSpinnerKorMapa
        function StartSpinnerKorMapaValueChanged(app, event)
            app.sourceData.stepZmeneny=true;
        end

        % Button pushed function: ZarovnajKorButton
        function ZarovnajKorButtonPushed(app, event)
            if length(app.DostupnesadyMapyListBox.Value)~=3
                uhOh(app,"Pre uspesne zarovnanie potrebujete mat vybrane tri mapy, ktorych nazvy obsahuju axial, coronal a sagittal.");
            elseif isempty(app.modelMultiple.axial)||isempty(app.modelMultiple.coronal)||isempty(app.modelMultiple.sagittal)
                uhOh(app,"Nemate spravne nacitane referencne snimky. Niektora z nich neobsahuje validne data.");
            else
                app.Stav.Text="Nacitavam modely...";
                useSkips.tf=true;
                useSkips.isCoreg=false;
                useSkips.start=app.StartSpinnerKorMapa.Value;
                useSkips.step=app.StepSpinnerKorMapa.Value;
                useSkips.stop=double.empty;
                [app.modelMultipleMaps.axial,app.modelMultipleMaps.coronal,app.modelMultipleMaps.coronalAddress,app.modelMultipleMaps.sagittal,app.modelMultipleMaps.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyMapyListBox.Value,app.DostupnesadyMapyListBox.Value,useSkips);
                app.Stav.Text="Zacinam registraciu...";
                [coronalRegistered,mapCoronalRegistered]=coregisterMapToOrigin(app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultipleMaps.coronal,app.modelMultiple.coronal,app.modelMultipleMaps.coronalAddress);
                [sagittalRegistered,mapSagittalRegistered]=coregisterMapToOrigin(app.modelMultiple.axial,app.modelMultiple.sagittal,app.modelMultipleMaps.sagittal,app.modelMultiple.sagittal,app.modelMultipleMaps.sagittalAddress);
                %                 [interpVolumeMap] = interpolateMaps(app.modelMultipleMaps.axial,coronalRegistered,sagittalRegistered);
                app.modelMultiple.coronal=coronalRegistered;
                app.modelMultiple.sagittal=sagittalRegistered;
                app.modelMultipleMaps.coronal=mapCoronalRegistered;
                app.modelMultipleMaps.sagittal=mapSagittalRegistered;
                app.Stav.Text="Zobrazujem modely...";
                %axial
                axModel=modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                colorbar(app.ZobrAx,'off')
                title(app.ZobrAx,'Axialne zobrazenie');
                hold(app.ZobrAx,"on");
                axMap=modelVisualizer(app.modelMultipleMaps.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaMaska);
                hold(app.ZobrAx,"off");
                %coronal
                coModel=modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                hold(app.ZobrCo,"on");
                coMap=modelVisualizer(app.modelMultipleMaps.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaMaska);
                hold(app.ZobrCo,"off");
                %sagittal
                colorbar(app.ZobrCo,'off')
                title(app.ZobrCo,'Koronalne zobrazenie');
                saModel=modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaHlavny);
                hold(app.ZobrSa,"on");
                saMap=modelVisualizer(app.modelMultipleMaps.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold,app.zobrazenieVlastnosti.mapaMaska);
                hold(app.ZobrSa,"off");
                colorbar(app.ZobrSa,'off')
                title(app.ZobrSa,'Sagitalne zobrazenie');
                set(app.ZobrAx,'Color','k'); set(app.ZobrCo,'Color','k'); set(app.ZobrSa,'Color','k');
                axis(app.ZobrAx,'equal'); axis(app.ZobrCo,'equal'); axis(app.ZobrSa,'equal');
                xlabel(app.ZobrAx,'X'); ylabel(app.ZobrAx,'Y'); zlabel(app.ZobrAx,'Z');
                xlabel(app.ZobrCo,'X'); ylabel(app.ZobrCo,'Y'); zlabel(app.ZobrCo,'Z');
                xlabel(app.ZobrSa,'X'); ylabel(app.ZobrSa,'Y'); zlabel(app.ZobrSa,'Z');
                view(app.ZobrAx,3); view(app.ZobrCo,3); view(app.ZobrSa,3);
                app.Stav.Text="Modely zobrazene!";
            end
        end

        % Button pushed function: VytvormodelInterpButtonKor
        function VytvormodelInterpButtonKorPushed(app, event)
            app.Stav.Text="Vytvaram interpolovany model...";
            if isempty(app.MenomodeluKorEditField.Value)==false
                app.modelMultiple.interpAddress=string(["niiData\"+app.MenomodeluKorEditField.Value+".nii"]);
                app.modelMultiple.interpLabel=app.MenomodeluKorEditField.Value;
            else
                app.modelMultiple.interpAddress="niiData\GenerickeMenoInterpolovanehoModelu.nii";
                app.modelMultiple.interpLabel='GenerickeMenoInterpolovanehoModelu';
            end
            if ~contains(app.HlavnymodelDropDown.Items,app.modelMultiple.interpLabel)
                app.HlavnymodelDropDown.Items(end+1)=app.HlavnymodelDropDown.Items(end);
                app.HlavnymodelDropDown.Items(end)={app.modelMultiple.interpLabel};
            end
            app.HlavnymodelDropDown.Value=app.modelMultiple.interpLabel;
            if ~isempty(app.modelMultiple)
                app.modelMultiple.main = interpolateMaps(app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.sagittal,app.modelMultiple.interpAddress);
                app.Stav.Text="Vytvaram interpolovanu masku...";
            else
                uhOh(app,"Ste si isti ze su spravne nacitane modely?");
            end
            if isempty(app.MenoMaskyKorEditField.Value)==false
                app.modelMultipleMaps.interpAddress=string(["niiData\"+app.MenoMaskyKorEditField.Value+".nii"]);
                app.modelMultipleMaps.interpLabel=app.MenoMaskyKorEditField.Value;
            else
                app.modelMultipleMaps.interpAddress="niiData\GenerickeMenoInterpolovanejMasky.nii";
                app.modelMultipleMaps.interpLabel='GenerickeMenoInterpolovanejMasky';
            end
            if ~contains(app.MapaDropDown.Items,app.modelMultipleMaps.interpLabel)
                app.MapaDropDown.Items(end+1)=app.MapaDropDown.Items(end);
                app.MapaDropDown.Items(end)={app.modelMultipleMaps.interpLabel};
            end
            if ~isempty(app.modelMultipleMaps)
                app.MapaDropDown.Value=app.modelMultipleMaps.interpLabel;
                app.modelMultipleMaps.main = interpolateMaps(app.modelMultipleMaps.axial,app.modelMultipleMaps.coronal,app.modelMultipleMaps.sagittal,app.modelMultipleMaps.interpAddress);
            else
                uhOh(app,"Ste si isti ze su spravne nacitane mapy?");
            end
            app.Stav.Text="Zobrazujem model...";
            app.zobrazenieVlastnosti.pouzitRoletky=true;
            app.PouzivathodnotyvdropdownochButton.Value=true;
            if ~isempty(app.modelMultiple.main)
                app.zobrazenieModel=app.modelMultiple.main;
            else
                uhOh(app,"Nebolo mozne zobrazit model!");
            end
            if ~isempty(app.modelMultipleMaps.main)
                app.zobrazenieMapa=app.modelMultipleMaps.main;
            else
                uhOh(app,"Nebolo mozne zobrazit mapu!");
            end
            drawRelevant(app);
            app.Stav.Text="Model zobrazeny!";
        end

        % Value changed function: MenomodeluKorEditField
        function MenomodeluKorEditFieldValueChanged(app, event)
            value = app.MenomodeluKorEditField.Value;
            
        end

        % Button pushed function: KXminusK
        function KXminusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,-90,[1 0 0]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,-90,[1 0 0]);
        end

        % Button pushed function: KXplusK
        function KXplusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[1 0 0]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,90,[1 0 0]);
        end

        % Button pushed function: KXflipK
        function KXflipKButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,1);
            app.modelMultipleMaps.coronal=flip(app.modelMultipleMaps.coronal,1);
        end

        % Button pushed function: KYminusK
        function KYminusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,-90,[0 1 0]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,-90,[0 1 0]);
        end

        % Button pushed function: KYplusK
        function KYplusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 1 0]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,90,[0 1 0]);
        end

        % Button pushed function: KYFlipK
        function KYFlipKButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,2);
            app.modelMultipleMaps.coronal=flip(app.modelMultipleMaps.coronal,2);
        end

        % Button pushed function: KZminusK
        function KZminusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,-90,[0 0 1]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,-90,[0 0 1]);
        end

        % Button pushed function: KZplusK
        function KZplusKButtonPushed(app, event)
            app.modelMultiple.coronal=imrotate3(app.modelMultiple.coronal,90,[0 0 1]);
            app.modelMultipleMaps.coronal=imrotate3(app.modelMultipleMaps.coronal,90,[0 0 1]);
        end

        % Button pushed function: KZflipK
        function KZflipKButtonPushed(app, event)
            app.modelMultiple.coronal=flip(app.modelMultiple.coronal,3);
            app.modelMultipleMaps.coronal=flip(app.modelMultipleMaps.coronal,3);
        end

        % Button pushed function: SXminusK
        function SXminusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,-90,[1 0 0]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,-90,[1 0 0]);
        end

        % Button pushed function: SYminusK
        function SYminusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,-90,[0 1 0]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,-90,[0 1 0]);
        end

        % Button pushed function: SZminusK
        function SZminusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,-90,[0 0 1]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,-90,[0 0 1]);
        end

        % Button pushed function: SXplusK
        function SXplusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,90,[1 0 0]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,90,[1 0 0]);
        end

        % Button pushed function: SYplusK
        function SYplusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,90,[0 1 0]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,90,[0 1 0]);
        end

        % Button pushed function: SZplusK
        function SZplusKButtonPushed(app, event)
            app.modelMultiple.sagittal=imrotate3(app.modelMultiple.sagittal,90,[0 0 1]);
            app.modelMultipleMaps.sagittal=imrotate3(app.modelMultipleMaps.sagittal,90,[0 0 1]);
        end

        % Button pushed function: SXflipK
        function SXflipKButtonPushed(app, event)
            app.modelMultiple.sagittal=flip(app.modelMultiple.sagittal,1);
            app.modelMultipleMaps.sagittal=flip(app.modelMultipleMaps.sagittal,1);
        end

        % Button pushed function: SYflipK
        function SYflipKButtonPushed(app, event)
            app.modelMultiple.sagittal=flip(app.modelMultiple.sagittal,2);
            app.modelMultipleMaps.sagittal=flip(app.modelMultipleMaps.sagittal,2);
        end

        % Button pushed function: SZflipK
        function SZflipKButtonPushed(app, event)
            app.modelMultiple.sagittal=flip(app.modelMultiple.sagittal,3);
            app.modelMultipleMaps.sagittal=flip(app.modelMultipleMaps.sagittal,3);
        end

        % Button pushed function: ZobrazmapyButton
        function ZobrazmapyButtonPushed(app, event)
            app.Stav.Text="Nacitavam modely...";
            useSkips.tf=true;
            useSkips.isCoreg=false;
            useSkips.start=app.StartSpinnerKorMapa.Value;
            useSkips.step=app.StepSpinnerKorMapa.Value;
            useSkips.stop=double.empty;
            if length(app.DostupnesadyMapyListBox.Value)~=3
                uhOh(app,"Pre uspesne zobrazenie potrebujete mat vybrane tri mapy, ktorych nazvy obsahuju axial, coronal a sagittal.");
            else
                [app.modelMultipleMaps.axial,app.modelMultipleMaps.coronal,app.modelMultipleMaps.coronalAddress,app.modelMultipleMaps.sagittal,app.modelMultipleMaps.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyMapyListBox.Value,app.DostupnesadyMapyListBox.Value,useSkips);
            end
            if isempty(app.modelMultiple.axial)||isempty(app.modelMultiple.coronal)||isempty(app.modelMultiple.sagittal)
                uhOh(app,"Nemate spravne nacitane referencne snimky. Niektora z nich neobsahuje validne data.");
            else
                app.modelMultipleMaps.inUse=true;
                drawRelevant(app);
            end
        end

        % Value changed function: StartSpinnerKorRefAx
        function StartSpinnerKorRefAxValueChanged(app, event)
            value = app.StartSpinnerKorRefAx.Value;
            
        end

        % Value changed function: StepSpinnerKorRefAx
        function StepSpinnerKorRefAxValueChanged(app, event)
            value = app.StepSpinnerKorRefAx.Value;
            
        end

        % Value changed function: StopSpinnerKorRefAx
        function StopSpinnerKorRefAxValueChanged(app, event)
            value = app.StopSpinnerKorRefAx.Value;
            
        end

        % Value changed function: StepSpinnerKorRefSa
        function StepSpinnerKorRefSaValueChanged(app, event)
            value = app.StepSpinnerKorRefSa.Value;
            
        end

        % Value changed function: StopSpinnerKorRefSa
        function StopSpinnerKorRefSaValueChanged(app, event)
            value = app.StopSpinnerKorRefSa.Value;
            
        end

        % Value changed function: StartSpinnerKorRefSa
        function StartSpinnerKorRefSaValueChanged(app, event)
            value = app.StartSpinnerKorRefSa.Value;
            
        end

        % Button pushed function: StopkaButton
        function StopkaButtonPushed(app, event)
            app.stopkaStatus=false;
            uiresume(app.UIFigure);
            app.StopkaButton.Enable=false;
            app.StopkaButton.Icon = 'icon-mat-help-outline.png';
            app.Stav.Text="Cakam na vstup...";
        end

        % Value changed function: MaskThresholdMapySlider
        function MaskThresholdMapySliderValueChanged(app, event)
            app.zobrazenieVlastnosti.mapaThreshold = app.MaskThresholdMapySlider.Value;
            app.MaskThresholdMapySpinner.Value = app.zobrazenieVlastnosti.mapaThreshold;
        end

        % Value changed function: MaskThresholdMapySpinner
        function MaskThresholdMapySpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.mapaThreshold = app.MaskThresholdMapySpinner.Value;
            app.MaskThresholdMapySlider.Value = app.zobrazenieVlastnosti.mapaThreshold;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {671, 671, 671};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {671, 671};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {446, '1x', 240};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.loadSourceDataShortcut = uipushtool(app.Toolbar);
            app.loadSourceDataShortcut.Tooltip = {'Znovu nacita zdroje z priecinkov sourceData a niiData.'};
            app.loadSourceDataShortcut.ClickedCallback = createCallbackFcn(app, @loadSourceDataShortcutClicked, true);
            app.loadSourceDataShortcut.Icon = 'icon-mat-refresh.png';
            app.refreshShortcut = uipushtool(app.Toolbar);
            app.refreshShortcut.Tooltip = {'Znovu vykresli 3D zobrazenie modelu.'};
            app.refreshShortcut.ClickedCallback = createCallbackFcn(app, @refreshShortcutClicked, true);
            app.refreshShortcut.Icon = 'icon-mat-3d-rotation.png';
            app.zarovnajMapuShortcut = uipushtool(app.Toolbar);
            app.zarovnajMapuShortcut.Visible = 'off';
            app.zarovnajMapuShortcut.ClickedCallback = createCallbackFcn(app, @zarovnajMapuShortcutClicked, true);
            app.zarovnajMapuShortcut.Enable = 'off';
            app.zarovnajMapuShortcut.Icon = 'icon-mat-zoom-out-map.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.TvorbazdrojovychdatTab = uitab(app.TabGroup);
            app.TvorbazdrojovychdatTab.Title = 'Tvorba zdrojovych dat';
            app.ZobrazenieJednaPanel = uipanel(app.TvorbazdrojovychdatTab);
            app.ZobrazenieJednaPanel.Title = 'Zobrazenie z jednej sady snimok';
            app.GridLayout4 = uigridlayout(app.ZobrazenieJednaPanel);
            app.VytvormodelJButton = uibutton(app.GridLayout4, 'push');
            app.VytvormodelJButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelJButtonPushed, true);
            app.VytvormodelJButton.Text = 'Vytvor model';
            app.DostupnesadyJListBox = uilistbox(app.GridLayout4);
            app.DostupnesadyJListBox.Items = {'aaa'};
            app.DostupnesadyJListBox.Value = {};
            app.DostupnesadysnimokListBoxLabel = uilabel(app.GridLayout4);
            app.DostupnesadysnimokListBoxLabel.Text = 'Dostupne sady snimok';
            app.ZobrazenieViacPanel = uipanel(app.TvorbazdrojovychdatTab);
            app.ZobrazenieViacPanel.Title = 'Zobrazenie z viacerých sád snímok';
            app.GridLayout3 = uigridlayout(app.ZobrazenieViacPanel);
            app.VytvormodelInterpButton = uibutton(app.GridLayout3, 'push');
            app.VytvormodelInterpButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelInterpButtonPushed, true);
            app.VytvormodelInterpButton.Text = 'Vytvor interpolovany model';
            app.VytvormodelVButton = uibutton(app.GridLayout3, 'push');
            app.VytvormodelVButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelVButtonPushed, true);
            app.VytvormodelVButton.Text = 'Vytvor modely';
            app.DostupnesadyVListBox = uilistbox(app.GridLayout3);
            app.DostupnesadyVListBox.Multiselect = 'on';
            app.DostupnesadyVListBox.Value = {'Item 1'};
            app.DostupnesadysnimokListBox_2Label = uilabel(app.GridLayout3);
            app.DostupnesadysnimokListBox_2Label.Text = 'Dostupne sady snimok';
            app.MenomodeluEditFieldLabel = uilabel(app.GridLayout3);
            app.MenomodeluEditFieldLabel.Text = 'Meno modelu';
            app.MenomodeluEditField = uieditfield(app.GridLayout3, 'text');
            app.KorekciemodelovPanel = uipanel(app.GridLayout3);
            app.KorekciemodelovPanel.Title = 'Korekcie modelov';
            app.GridLayout7 = uigridlayout(app.KorekciemodelovPanel);
            app.ZAPVYPButton_2 = uibutton(app.GridLayout7, 'state');
            app.ZAPVYPButton_2.Text = 'ZAP/VYP';
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.KoronalnyTab = uitab(app.TabGroup2);
            app.KoronalnyTab.Title = 'Koronalny';
            app.GridLayout10 = uigridlayout(app.KoronalnyTab);
            app.XLabel = uilabel(app.GridLayout10);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout10);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout10);
            app.ZLabel.Text = 'Z';
            app.KXminus = uibutton(app.GridLayout10, 'push');
            app.KXminus.ButtonPushedFcn = createCallbackFcn(app, @KXminusButtonPushed, true);
            app.KXminus.Text = '-90';
            app.KXplus = uibutton(app.GridLayout10, 'push');
            app.KXplus.ButtonPushedFcn = createCallbackFcn(app, @KXplusButtonPushed, true);
            app.KXplus.Text = '+90';
            app.KXflip = uibutton(app.GridLayout10, 'push');
            app.KXflip.ButtonPushedFcn = createCallbackFcn(app, @KXflipButtonPushed, true);
            app.KXflip.Text = 'Flip';
            app.KYminus = uibutton(app.GridLayout10, 'push');
            app.KYminus.ButtonPushedFcn = createCallbackFcn(app, @KYminusButtonPushed, true);
            app.KYminus.Text = '-90';
            app.KZplus = uibutton(app.GridLayout10, 'push');
            app.KZplus.ButtonPushedFcn = createCallbackFcn(app, @KZplusButtonPushed, true);
            app.KZplus.Text = '+90';
            app.KYFlip = uibutton(app.GridLayout10, 'push');
            app.KYFlip.ButtonPushedFcn = createCallbackFcn(app, @KYFlipButtonPushed, true);
            app.KYFlip.Text = 'Flip';
            app.KZminus = uibutton(app.GridLayout10, 'push');
            app.KZminus.ButtonPushedFcn = createCallbackFcn(app, @KZminusButtonPushed, true);
            app.KZminus.Text = '-90';
            app.KYplus = uibutton(app.GridLayout10, 'push');
            app.KYplus.ButtonPushedFcn = createCallbackFcn(app, @KYplusButtonPushed, true);
            app.KYplus.Text = '+90';
            app.KZflip = uibutton(app.GridLayout10, 'push');
            app.KZflip.ButtonPushedFcn = createCallbackFcn(app, @KZflipButtonPushed, true);
            app.KZflip.Text = 'Flip';
            app.SagitalnyTab = uitab(app.TabGroup2);
            app.SagitalnyTab.Title = 'Sagitalny';
            app.GridLayout11 = uigridlayout(app.SagitalnyTab);
            app.XLabel_2 = uilabel(app.GridLayout11);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout11);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.GridLayout11);
            app.ZLabel_2.Text = 'Z';
            app.SXminus = uibutton(app.GridLayout11, 'push');
            app.SXminus.Text = '-90';
            app.SYplus = uibutton(app.GridLayout11, 'push');
            app.SYplus.Text = '+90';
            app.SYflip = uibutton(app.GridLayout11, 'push');
            app.SYflip.Text = 'Flip';
            app.SYminus = uibutton(app.GridLayout11, 'push');
            app.SYminus.Text = '-90';
            app.SXplus = uibutton(app.GridLayout11, 'push');
            app.SXplus.Text = '+90';
            app.SXflip = uibutton(app.GridLayout11, 'push');
            app.SXflip.Text = 'Flip';
            app.SZminus = uibutton(app.GridLayout11, 'push');
            app.SZminus.Text = '-90';
            app.SZplus = uibutton(app.GridLayout11, 'push');
            app.SZplus.Text = '+90';
            app.SZflip = uibutton(app.GridLayout11, 'push');
            app.SZflip.Text = 'Flip';
            app.AplikovatkorekcieLabel = uilabel(app.GridLayout7);
            app.AplikovatkorekcieLabel.Text = 'Aplikovat korekcie';
            app.pouzitkazduLabel = uilabel(app.GridLayout3);
            app.pouzitkazduLabel.Text = 'pouzit kazdu';
            app.StepSpinner = uispinner(app.GridLayout3);
            app.StepSpinner.Limits = [1 Inf];
            app.StepSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerValueChanged, true);
            app.StepSpinner.Value = 1;
            app.snimkuLabel = uilabel(app.GridLayout3);
            app.snimkuLabel.Text = 'snimku';
            app.spociatocnousnimkouLabel = uilabel(app.GridLayout3);
            app.spociatocnousnimkouLabel.Text = 's pociatocnou snimkou';
            app.StartSpinner = uispinner(app.GridLayout3);
            app.StartSpinner.Limits = [1 Inf];
            app.StartSpinner.Value = 1;
            app.akoncovousnimkouLabel = uilabel(app.GridLayout3);
            app.akoncovousnimkouLabel.Text = 'a koncovou snimkou';
            app.StopSpinner = uispinner(app.GridLayout3);
            app.StopSpinner.Limits = [1 Inf];
            app.StopSpinner.Value = 125;
            app.ZdrojedatDropDown = uidropdown(app.TvorbazdrojovychdatTab);
            app.ZdrojedatDropDown.Items = {'jeden', 'viacero', 'koregistracia mapy k modelu'};
            app.ZdrojedatDropDown.ValueChangedFcn = createCallbackFcn(app, @ZdrojedatDropDownValueChanged, true);
            app.ZdrojedatDropDown.Value = 'jeden';
            app.ZdrojedatDropDownLabel = uilabel(app.TvorbazdrojovychdatTab);
            app.ZdrojedatDropDownLabel.Text = 'Zdroje dat';
            app.KoregistraciaPanel = uipanel(app.TvorbazdrojovychdatTab);
            app.KoregistraciaPanel.Title = 'Koregistracia mapy s modelom';
            app.KoregMriezka = uigridlayout(app.KoregistraciaPanel);
            app.TabGroup3 = uitabgroup(app.KoregMriezka);
            app.ReferencnesnimkyTab = uitab(app.TabGroup3);
            app.ReferencnesnimkyTab.Title = 'Referencne snimky';
            app.GridLayout13 = uigridlayout(app.ReferencnesnimkyTab);
            app.GridLayout18 = uigridlayout(app.GridLayout13);
            app.DostupnesadyRefListBox = uilistbox(app.GridLayout18);
            app.DostupnesadyRefListBox.Multiselect = 'on';
            app.DostupnesadyRefListBox.Value = {'Item 1'};
            app.DostupnesadysnimokreferencneLabel = uilabel(app.GridLayout18);
            app.DostupnesadysnimokreferencneLabel.Text = 'Dostupne sady snimok - referencne';
            app.TabGroup4 = uitabgroup(app.GridLayout18);
            app.AxialnyTab = uitab(app.TabGroup4);
            app.AxialnyTab.Title = 'Axialny';
            app.GridLayout15 = uigridlayout(app.AxialnyTab);
            app.akoncovousnimkouLabel_2 = uilabel(app.GridLayout15);
            app.akoncovousnimkouLabel_2.Text = 'a koncovou snimkou';
            app.spociatocnousnimkouLabel_3 = uilabel(app.GridLayout15);
            app.spociatocnousnimkouLabel_3.Text = 's pociatocnou snimkou';
            app.StartSpinnerKorRefAx = uispinner(app.GridLayout15);
            app.StartSpinnerKorRefAx.Limits = [1 Inf];
            app.StartSpinnerKorRefAx.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerKorRefAxValueChanged, true);
            app.StartSpinnerKorRefAx.Value = 1;
            app.snimkuLabel_3 = uilabel(app.GridLayout15);
            app.snimkuLabel_3.Text = 'snimku';
            app.StepSpinnerKorRefAx = uispinner(app.GridLayout15);
            app.StepSpinnerKorRefAx.Limits = [1 Inf];
            app.StepSpinnerKorRefAx.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerKorRefAxValueChanged, true);
            app.StepSpinnerKorRefAx.Value = 1;
            app.StopSpinnerKorRefAx = uispinner(app.GridLayout15);
            app.StopSpinnerKorRefAx.Limits = [1 Inf];
            app.StopSpinnerKorRefAx.ValueChangedFcn = createCallbackFcn(app, @StopSpinnerKorRefAxValueChanged, true);
            app.StopSpinnerKorRefAx.Value = 25;
            app.pouzitkazduAx = uilabel(app.GridLayout15);
            app.pouzitkazduAx.Text = 'pouzit kazdu';
            app.KoronalnyTab_3 = uitab(app.TabGroup4);
            app.KoronalnyTab_3.Title = 'Koronalny';
            app.GridLayout16 = uigridlayout(app.KoronalnyTab_3);
            app.pouzitkazduLabel_4 = uilabel(app.GridLayout16);
            app.pouzitkazduLabel_4.Text = 'pouzit kazdu';
            app.spociatocnousnimkouLabel_4 = uilabel(app.GridLayout16);
            app.spociatocnousnimkouLabel_4.Text = 's pociatocnou snimkou';
            app.akoncovousnimkouLabel_3 = uilabel(app.GridLayout16);
            app.akoncovousnimkouLabel_3.Text = 'a koncovou snimkou';
            app.StepSpinnerKorRefCo = uispinner(app.GridLayout16);
            app.StepSpinnerKorRefCo.Limits = [1 Inf];
            app.StepSpinnerKorRefCo.Value = 25;
            app.snimkuLabel_4 = uilabel(app.GridLayout16);
            app.snimkuLabel_4.Text = 'snimku';
            app.StartSpinnerKorRefCo = uispinner(app.GridLayout16);
            app.StartSpinnerKorRefCo.Limits = [1 Inf];
            app.StartSpinnerKorRefCo.Value = 1;
            app.StopSpinnerKorRefCo = uispinner(app.GridLayout16);
            app.StopSpinnerKorRefCo.Limits = [1 Inf];
            app.StopSpinnerKorRefCo.Value = 601;
            app.SagitalnyTab_3 = uitab(app.TabGroup4);
            app.SagitalnyTab_3.Title = 'Sagitalny';
            app.GridLayout17 = uigridlayout(app.SagitalnyTab_3);
            app.pouzitkazduLabel_5 = uilabel(app.GridLayout17);
            app.pouzitkazduLabel_5.Text = 'pouzit kazdu';
            app.StepSpinnerKorRefSa = uispinner(app.GridLayout17);
            app.StepSpinnerKorRefSa.Limits = [1 Inf];
            app.StepSpinnerKorRefSa.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerKorRefSaValueChanged, true);
            app.StepSpinnerKorRefSa.Value = 25;
            app.snimkuLabel_5 = uilabel(app.GridLayout17);
            app.snimkuLabel_5.Text = 'snimku';
            app.spociatocnousnimkouLabel_5 = uilabel(app.GridLayout17);
            app.spociatocnousnimkouLabel_5.Text = 's pociatocnou snimkou';
            app.akoncovousnimkouLabel_4 = uilabel(app.GridLayout17);
            app.akoncovousnimkouLabel_4.Text = 'a koncovou snimkou';
            app.StopSpinnerKorRefSa = uispinner(app.GridLayout17);
            app.StopSpinnerKorRefSa.Limits = [1 Inf];
            app.StopSpinnerKorRefSa.ValueChangedFcn = createCallbackFcn(app, @StopSpinnerKorRefSaValueChanged, true);
            app.StopSpinnerKorRefSa.Value = 601;
            app.StartSpinnerKorRefSa = uispinner(app.GridLayout17);
            app.StartSpinnerKorRefSa.Limits = [1 Inf];
            app.StartSpinnerKorRefSa.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerKorRefSaValueChanged, true);
            app.StartSpinnerKorRefSa.Value = 1;
            app.ZobrazmodelKorButton = uibutton(app.GridLayout18, 'push');
            app.ZobrazmodelKorButton.ButtonPushedFcn = createCallbackFcn(app, @ZobrazmodelKorButtonPushed, true);
            app.ZobrazmodelKorButton.Text = 'Zobraz referencne modely';
            app.MapyTab = uitab(app.TabGroup3);
            app.MapyTab.Title = 'Mapy';
            app.GridLayout14 = uigridlayout(app.MapyTab);
            app.DostupnesadysnimokmapyLabel = uilabel(app.GridLayout14);
            app.DostupnesadysnimokmapyLabel.Text = 'Dostupne sady snimok -  mapy';
            app.DostupnesadyMapyListBox = uilistbox(app.GridLayout14);
            app.DostupnesadyMapyListBox.Multiselect = 'on';
            app.DostupnesadyMapyListBox.Value = {'Item 1'};
            app.StartSpinnerKorMapa = uispinner(app.GridLayout14);
            app.StartSpinnerKorMapa.Limits = [1 Inf];
            app.StartSpinnerKorMapa.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerKorMapaValueChanged, true);
            app.StartSpinnerKorMapa.Value = 1;
            app.spociatocnousnimkouLabel_2 = uilabel(app.GridLayout14);
            app.spociatocnousnimkouLabel_2.Text = 's pociatocnou snimkou';
            app.snimkuLabel_2 = uilabel(app.GridLayout14);
            app.snimkuLabel_2.Text = 'snimku';
            app.StepSpinnerKorMapa = uispinner(app.GridLayout14);
            app.StepSpinnerKorMapa.Limits = [1 Inf];
            app.StepSpinnerKorMapa.ValueChangedFcn = createCallbackFcn(app, @StepSpinnerKorMapaValueChanged, true);
            app.StepSpinnerKorMapa.Value = 5;
            app.pouzitkazduLabel_2 = uilabel(app.GridLayout14);
            app.pouzitkazduLabel_2.Text = 'pouzit kazdu';
            app.ZobrazmapyButton = uibutton(app.GridLayout14, 'push');
            app.ZobrazmapyButton.ButtonPushedFcn = createCallbackFcn(app, @ZobrazmapyButtonPushed, true);
            app.ZobrazmapyButton.Text = 'Zobraz mapy';
            app.KorekcieTab_2 = uitab(app.TabGroup3);
            app.KorekcieTab_2.Title = 'Korekcie';
            app.GridLayout21 = uigridlayout(app.KorekcieTab_2);
            app.TabGroup2_2 = uitabgroup(app.GridLayout21);
            app.KoronalnyTabK = uitab(app.TabGroup2_2);
            app.KoronalnyTabK.Title = 'Koronalny';
            app.GridLayout10_2 = uigridlayout(app.KoronalnyTabK);
            app.XLabelK = uilabel(app.GridLayout10_2);
            app.XLabelK.Text = 'X';
            app.YLabelK = uilabel(app.GridLayout10_2);
            app.YLabelK.Text = 'Y';
            app.ZLabelK = uilabel(app.GridLayout10_2);
            app.ZLabelK.Text = 'Z';
            app.KXminusK = uibutton(app.GridLayout10_2, 'push');
            app.KXminusK.ButtonPushedFcn = createCallbackFcn(app, @KXminusKButtonPushed, true);
            app.KXminusK.Text = '-90';
            app.KXplusK = uibutton(app.GridLayout10_2, 'push');
            app.KXplusK.ButtonPushedFcn = createCallbackFcn(app, @KXplusKButtonPushed, true);
            app.KXplusK.Text = '+90';
            app.KXflipK = uibutton(app.GridLayout10_2, 'push');
            app.KXflipK.ButtonPushedFcn = createCallbackFcn(app, @KXflipKButtonPushed, true);
            app.KXflipK.Text = 'Flip';
            app.KYminusK = uibutton(app.GridLayout10_2, 'push');
            app.KYminusK.ButtonPushedFcn = createCallbackFcn(app, @KYminusKButtonPushed, true);
            app.KYminusK.Text = '-90';
            app.KZplusK = uibutton(app.GridLayout10_2, 'push');
            app.KZplusK.ButtonPushedFcn = createCallbackFcn(app, @KZplusKButtonPushed, true);
            app.KZplusK.Text = '+90';
            app.KYFlipK = uibutton(app.GridLayout10_2, 'push');
            app.KYFlipK.ButtonPushedFcn = createCallbackFcn(app, @KYFlipKButtonPushed, true);
            app.KYFlipK.Text = 'Flip';
            app.KZminusK = uibutton(app.GridLayout10_2, 'push');
            app.KZminusK.ButtonPushedFcn = createCallbackFcn(app, @KZminusKButtonPushed, true);
            app.KZminusK.Text = '-90';
            app.KYplusK = uibutton(app.GridLayout10_2, 'push');
            app.KYplusK.ButtonPushedFcn = createCallbackFcn(app, @KYplusKButtonPushed, true);
            app.KYplusK.Text = '+90';
            app.KZflipK = uibutton(app.GridLayout10_2, 'push');
            app.KZflipK.ButtonPushedFcn = createCallbackFcn(app, @KZflipKButtonPushed, true);
            app.KZflipK.Text = 'Flip';
            app.SagitalnyTabK = uitab(app.TabGroup2_2);
            app.SagitalnyTabK.Title = 'Sagitalny';
            app.GridLayout11_2 = uigridlayout(app.SagitalnyTabK);
            app.XLabel_3 = uilabel(app.GridLayout11_2);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.GridLayout11_2);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.GridLayout11_2);
            app.ZLabel_3.Text = 'Z';
            app.SXminusK = uibutton(app.GridLayout11_2, 'push');
            app.SXminusK.ButtonPushedFcn = createCallbackFcn(app, @SXminusKButtonPushed, true);
            app.SXminusK.Text = '-90';
            app.SYplusK = uibutton(app.GridLayout11_2, 'push');
            app.SYplusK.ButtonPushedFcn = createCallbackFcn(app, @SYplusKButtonPushed, true);
            app.SYplusK.Text = '+90';
            app.SYflipK = uibutton(app.GridLayout11_2, 'push');
            app.SYflipK.ButtonPushedFcn = createCallbackFcn(app, @SYflipKButtonPushed, true);
            app.SYflipK.Text = 'Flip';
            app.SYminusK = uibutton(app.GridLayout11_2, 'push');
            app.SYminusK.ButtonPushedFcn = createCallbackFcn(app, @SYminusKButtonPushed, true);
            app.SYminusK.Text = '-90';
            app.SXplusK = uibutton(app.GridLayout11_2, 'push');
            app.SXplusK.ButtonPushedFcn = createCallbackFcn(app, @SXplusKButtonPushed, true);
            app.SXplusK.Text = '+90';
            app.SXflipK = uibutton(app.GridLayout11_2, 'push');
            app.SXflipK.ButtonPushedFcn = createCallbackFcn(app, @SXflipKButtonPushed, true);
            app.SXflipK.Text = 'Flip';
            app.SZminusK = uibutton(app.GridLayout11_2, 'push');
            app.SZminusK.ButtonPushedFcn = createCallbackFcn(app, @SZminusKButtonPushed, true);
            app.SZminusK.Text = '-90';
            app.SZplusK = uibutton(app.GridLayout11_2, 'push');
            app.SZplusK.ButtonPushedFcn = createCallbackFcn(app, @SZplusKButtonPushed, true);
            app.SZplusK.Text = '+90';
            app.SZflipK = uibutton(app.GridLayout11_2, 'push');
            app.SZflipK.ButtonPushedFcn = createCallbackFcn(app, @SZflipKButtonPushed, true);
            app.SZflipK.Text = 'Flip';
            app.InterpolovanymodelTab = uitab(app.TabGroup3);
            app.InterpolovanymodelTab.Title = 'Interpolovany model';
            app.GridLayout20 = uigridlayout(app.InterpolovanymodelTab);
            app.MenomodeluKorEditField = uieditfield(app.GridLayout20, 'text');
            app.MenomodeluKorEditField.ValueChangedFcn = createCallbackFcn(app, @MenomodeluKorEditFieldValueChanged, true);
            app.MenomodeluEditField_2Label = uilabel(app.GridLayout20);
            app.MenomodeluEditField_2Label.Text = 'Meno modelu';
            app.MenoMaskyKorEditField = uieditfield(app.GridLayout20, 'text');
            app.MenomodeluEditField_2Label_2 = uilabel(app.GridLayout20);
            app.MenomodeluEditField_2Label_2.Text = 'Meno masky';
            app.VytvormodelInterpButtonKor = uibutton(app.GridLayout20, 'push');
            app.VytvormodelInterpButtonKor.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelInterpButtonKorPushed, true);
            app.VytvormodelInterpButtonKor.Text = 'Vytvor interpolovany model aj s maskou';
            app.ZarovnajKorButton = uibutton(app.GridLayout20, 'push');
            app.ZarovnajKorButton.ButtonPushedFcn = createCallbackFcn(app, @ZarovnajKorButtonPushed, true);
            app.ZarovnajKorButton.Text = 'Zarovnaj masky k snimkam';
            app.NastaveniazobrazeniaTab = uitab(app.TabGroup);
            app.NastaveniazobrazeniaTab.Title = 'Nastavenia zobrazenia';
            app.GridLayout5 = uigridlayout(app.NastaveniazobrazeniaTab);
            app.RezimprojekcieButtonGroup = uibuttongroup(app.GridLayout5);
            app.RezimprojekcieButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RezimprojekcieButtonGroupSelectionChanged, true);
            app.RezimprojekcieButtonGroup.Title = 'Rezim projekcie';
            app.OrthographicButton_2 = uiradiobutton(app.RezimprojekcieButtonGroup);
            app.OrthographicButton_2.Text = {'Orthographic'; ''};
            app.OrthographicButton_2.Value = true;
            app.PerspectiveButton = uiradiobutton(app.RezimprojekcieButtonGroup);
            app.PerspectiveButton.Text = {'Perspective'; ''};
            app.MaskThresholdModeluSpinner = uispinner(app.GridLayout5);
            app.MaskThresholdModeluSpinner.Step = 0.05;
            app.MaskThresholdModeluSpinner.Limits = [0.01 1];
            app.MaskThresholdModeluSpinner.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdModeluSpinnerValueChanged, true);
            app.MaskThresholdModeluSpinner.Value = 0.2;
            app.InterpolaciaSpinner = uispinner(app.GridLayout5);
            app.InterpolaciaSpinner.Step = 2;
            app.InterpolaciaSpinner.Limits = [1 64];
            app.InterpolaciaSpinner.ValueChangedFcn = createCallbackFcn(app, @InterpolaciaSpinnerValueChanged, true);
            app.InterpolaciaSpinner.Value = 8;
            app.InterpolaciaSpinnerLabel = uilabel(app.GridLayout5);
            app.InterpolaciaSpinnerLabel.Text = 'Interpolacia';
            app.MaskThresholdModeluSlider = uislider(app.GridLayout5);
            app.MaskThresholdModeluSlider.Limits = [0.01 1];
            app.MaskThresholdModeluSlider.MajorTicks = [0.01 0.25 0.5 0.75 1];
            app.MaskThresholdModeluSlider.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdModeluSliderValueChanged, true);
            app.MaskThresholdModeluSlider.Value = 0.2;
            app.MaskovacithresholdmodeluLabel = uilabel(app.GridLayout5);
            app.MaskovacithresholdmodeluLabel.Text = 'Maskovaci threshold modelu';
            app.MaskMultiplier = uislider(app.GridLayout5);
            app.MaskMultiplier.Limits = [1 25];
            app.MaskMultiplier.ValueChangedFcn = createCallbackFcn(app, @MaskMultiplierValueChanged, true);
            app.MaskMultiplier.Value = 2.5;
            app.VoxelovymultiplierLabel = uilabel(app.GridLayout5);
            app.VoxelovymultiplierLabel.Text = 'Voxelovy multiplier';
            app.FarebnostmodeluListBoxLabel = uilabel(app.GridLayout5);
            app.FarebnostmodeluListBoxLabel.Text = 'Farebnost modelu';
            app.FarebnostmodeluListBox = uilistbox(app.GridLayout5);
            app.FarebnostmodeluListBox.Items = {'gray', 'bone', 'copper', 'pink', 'hot'};
            app.FarebnostmodeluListBox.ValueChangedFcn = createCallbackFcn(app, @FarebnostmodeluListBoxValueChanged, true);
            app.FarebnostmodeluListBox.Value = 'gray';
            app.FarebnostprelozenejmapyListBoxLabel = uilabel(app.GridLayout5);
            app.FarebnostprelozenejmapyListBoxLabel.Text = 'Farebnost prelozenej mapy';
            app.FarebnostprelozenejmapyListBox = uilistbox(app.GridLayout5);
            app.FarebnostprelozenejmapyListBox.Items = {'parula', 'turbo', 'jet', 'lines', 'cool'};
            app.FarebnostprelozenejmapyListBox.ValueChangedFcn = createCallbackFcn(app, @FarebnostprelozenejmapyListBoxValueChanged, true);
            app.FarebnostprelozenejmapyListBox.Value = 'parula';
            app.MaskovacithresholdmapySliderLabel = uilabel(app.GridLayout5);
            app.MaskovacithresholdmapySliderLabel.Text = 'Maskovaci threshold mapy';
            app.MaskThresholdMapySlider = uislider(app.GridLayout5);
            app.MaskThresholdMapySlider.Limits = [0.01 1];
            app.MaskThresholdMapySlider.MajorTicks = [0.01 0.25 0.5 0.75 1];
            app.MaskThresholdMapySlider.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdMapySliderValueChanged, true);
            app.MaskThresholdMapySlider.Value = 0.2;
            app.MaskThresholdMapySpinner = uispinner(app.GridLayout5);
            app.MaskThresholdMapySpinner.Step = 0.05;
            app.MaskThresholdMapySpinner.Limits = [0.01 1];
            app.MaskThresholdMapySpinner.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdMapySpinnerValueChanged, true);
            app.MaskThresholdMapySpinner.Value = 0.2;
            app.LayeringTab = uitab(app.TabGroup);
            app.LayeringTab.Title = 'Layering';
            app.GridLayout6 = uigridlayout(app.LayeringTab);
            app.HlavnymodelDropDown = uidropdown(app.GridLayout6);
            app.HlavnymodelDropDown.Items = {'BEZ MODELU'};
            app.HlavnymodelDropDown.ValueChangedFcn = createCallbackFcn(app, @HlavnymodelDropDownValueChanged, true);
            app.HlavnymodelDropDown.Value = 'BEZ MODELU';
            app.HlavnymodelDropDownLabel = uilabel(app.GridLayout6);
            app.HlavnymodelDropDownLabel.Text = 'Hlavny model';
            app.MapanaprelozenieLabel = uilabel(app.GridLayout6);
            app.MapanaprelozenieLabel.Text = 'Mapa na prelozenie';
            app.MapaDropDown = uidropdown(app.GridLayout6);
            app.MapaDropDown.Items = {'BEZ MAPY', ''};
            app.MapaDropDown.ValueChangedFcn = createCallbackFcn(app, @MapaDropDownValueChanged, true);
            app.MapaDropDown.Value = 'BEZ MAPY';
            app.PouzivathodnotyvdropdownochButton = uibutton(app.GridLayout6, 'state');
            app.PouzivathodnotyvdropdownochButton.ValueChangedFcn = createCallbackFcn(app, @PouzivathodnotyvdropdownochButtonValueChanged, true);
            app.PouzivathodnotyvdropdownochButton.Text = 'Pouzivat hodnoty v dropdownoch';
            app.CenterPanel = uipanel(app.GridLayout);
            app.StredGroup = uitabgroup(app.CenterPanel);
            app.ZobModTab = uitab(app.StredGroup);
            app.ZobModTab.Title = '3D zobrazenie modelu';
            app.ViewerPanel = uipanel(app.ZobModTab);
            app.GridLayout12 = uigridlayout(app.ViewerPanel);
            app.Viewer = uiaxes(app.GridLayout12);
            title(app.Viewer, 'Title')
            xlabel(app.Viewer, 'X')
            ylabel(app.Viewer, 'Y')
            zlabel(app.Viewer, 'Z')
            app.ZobZarTab = uitab(app.StredGroup);
            app.ZobZarTab.Title = 'Zobrazenie pre zarovnanie modelov';
            app.ZobrPanel = uipanel(app.ZobZarTab);
            app.GridLayout2 = uigridlayout(app.ZobrPanel);
            app.ZobrAx = uiaxes(app.GridLayout2);
            title(app.ZobrAx, 'Title')
            xlabel(app.ZobrAx, 'X')
            ylabel(app.ZobrAx, 'Y')
            zlabel(app.ZobrAx, 'Z')
            app.ZobrCo = uiaxes(app.GridLayout2);
            title(app.ZobrCo, 'Title')
            xlabel(app.ZobrCo, 'X')
            ylabel(app.ZobrCo, 'Y')
            zlabel(app.ZobrCo, 'Z')
            app.ZobrSa = uiaxes(app.GridLayout2);
            title(app.ZobrSa, 'Title')
            xlabel(app.ZobrSa, 'X')
            ylabel(app.ZobrSa, 'Y')
            zlabel(app.ZobrSa, 'Z')
            app.ZobrAxMapa = uiaxes(app.GridLayout2);
            title(app.ZobrAxMapa, 'Title')
            xlabel(app.ZobrAxMapa, 'X')
            ylabel(app.ZobrAxMapa, 'Y')
            zlabel(app.ZobrAxMapa, 'Z')
            app.ZobrCoMapa = uiaxes(app.GridLayout2);
            title(app.ZobrCoMapa, 'Title')
            xlabel(app.ZobrCoMapa, 'X')
            ylabel(app.ZobrCoMapa, 'Y')
            zlabel(app.ZobrCoMapa, 'Z')
            app.ZobrSaMapa = uiaxes(app.GridLayout2);
            title(app.ZobrSaMapa, 'Title')
            xlabel(app.ZobrSaMapa, 'X')
            ylabel(app.ZobrSaMapa, 'Y')
            zlabel(app.ZobrSaMapa, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.RightPanel);
            app.Stav = uilabel(app.GridLayout8);
            app.Stav.Text = 'Stavovy riadok';
            app.VrstvyPanel = uipanel(app.GridLayout8);
            app.VrstvyPanel.Title = 'Vrstvy';
            app.VrstvyLayout = uigridlayout(app.VrstvyPanel);
            app.XHSlider = uislider(app.VrstvyLayout);
            app.XSliderLabel = uilabel(app.VrstvyLayout);
            app.XSliderLabel.Text = 'X';
            app.YLSlider = uislider(app.VrstvyLayout);
            app.YSliderLabel = uilabel(app.VrstvyLayout);
            app.YSliderLabel.Text = 'Y';
            app.ZLSlider = uislider(app.VrstvyLayout);
            app.ZSliderLabel = uilabel(app.VrstvyLayout);
            app.ZSliderLabel.Text = 'Z';
            app.XLSlider = uislider(app.VrstvyLayout);
            app.YHSlider = uislider(app.VrstvyLayout);
            app.ZHSlider = uislider(app.VrstvyLayout);
            app.PrehliadaniepovrstvachLabel = uilabel(app.GridLayout8);
            app.PrehliadaniepovrstvachLabel.Text = 'Prehliadanie po vrstvach';
            app.ZAPVYPButton = uibutton(app.GridLayout8, 'state');
            app.ZAPVYPButton.Text = 'ZAP/VYP';
            app.ZrotSlider = uislider(app.GridLayout8);
            app.ZrotSliderLabel = uilabel(app.GridLayout8);
            app.ZrotSliderLabel.Text = 'Z rot';
            app.XYrotSlider = uislider(app.GridLayout8);
            app.XYrotSlider.ValueChangingFcn = createCallbackFcn(app, @XYrotSliderValueChanging, true);
            app.XYrotSliderLabel = uilabel(app.GridLayout8);
            app.XYrotSliderLabel.Text = 'XY rot';
            app.StopkaButton = uibutton(app.GridLayout8, 'push');
            app.StopkaButton.ButtonPushedFcn = createCallbackFcn(app, @StopkaButtonPushed, true);
            app.StopkaButton.Icon = 'icon-mat-help-outline.png';
            app.StopkaButton.Enable = 'off';
            app.StopkaButton.Tooltip = {'Ked program narazi na chybu, zastavi sa. Precitajte si chybovu hlasku a tymto tlacidlom ju odsuhlaste.'};
            app.StopkaButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end